classdef LowerSOA_Participant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        LabelNumericEditField2  matlab.ui.control.Label
        NumericEditField2       matlab.ui.control.NumericEditField
        LabelNumericEditField   matlab.ui.control.Label
        NumericEditField        matlab.ui.control.NumericEditField
        Button                  matlab.ui.control.Button
        LabelNumericEditField3  matlab.ui.control.Label
        NumericEditField3       matlab.ui.control.NumericEditField
        LabelDropDown           matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        LabelEditField          matlab.ui.control.Label
        EditField               matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        Label2                  matlab.ui.control.Label
        NumericEditField4       matlab.ui.control.NumericEditField
        Label3                  matlab.ui.control.Label
        NumericEditField5       matlab.ui.control.NumericEditField
        Label4                  matlab.ui.control.Label
        NumericEditField6       matlab.ui.control.NumericEditField
        Label5                  matlab.ui.control.Label
        NumericEditField7       matlab.ui.control.NumericEditField
        Button2                 matlab.ui.control.Button
        Button3                 matlab.ui.control.Button
        Label6                  matlab.ui.control.Label
        LabelNumericEditField8  matlab.ui.control.Label
        NumericEditField8       matlab.ui.control.NumericEditField
        Button4                 matlab.ui.control.Button
        Button5                 matlab.ui.control.Button
        LabelEditField2         matlab.ui.control.Label
        EditField2              matlab.ui.control.EditField
        Label7                  matlab.ui.control.Label
        Label8                  matlab.ui.control.Label
    end


    properties (Access = private)
        interleaver % Interleaver object
        trials % Elapsed trials
        SOAs %SOAs tested
    end

    methods (Access = private)
        function save_results(app)
            X = 0:1:app.trials;
            X = transpose(X);
            M = [X,app.SOAs];
            M=M';
            base_name = [app.EditField.Value,'_',app.DropDown.Value,'_Lower_Methods.csv'];
            if exist(base_name)
                count = 1;
                name = [app.EditField.Value,'_',app.DropDown.Value,'_Lower_Methods(',num2str(count),').csv'];
                while exist(name)
                    count = count+1;
                    name = [app.EditField.Value,'_',app.DropDown.Value,'_Lower_Methods(',num2str(count),').csv'];
                end
                fileID = fopen(name,'w');
            else 
               fileID = fopen(base_name,'w');
            end
            
            format = '%d,%d,%d\n';
            fprintf(fileID,format,M);
            fclose(fileID);
            
            base_name = [app.EditField.Value,'_',app.DropDown.Value,'_Lower_SOA.txt'];
             if exist(base_name)
                count = 1;
                name = [app.EditField.Value,'_',app.DropDown.Value,'_Lower_SOA(',num2str(count),').txt'];
                while exist(name)
                    count = count+1;
                    name = [app.EditField.Value,'_',app.DropDown.Value,'_Lower_SOA(',num2str(count),').txt'];
                end
                fileID = fopen(name,'w');
            else 
               fileID = fopen(base_name,'w');
            end
            
            [std_1, std_2] = app.interleaver.calculate_standard_deviations();
            fprintf(fileID,['mean_soa_both_methods:',num2str(app.interleaver.calculate_result()),'\n']);
            fprintf(fileID,['ascending_soa:',num2str(app.NumericEditField.Value),'\n']);
            fprintf(fileID,['descending_soa:',num2str(app.NumericEditField4.Value),'\n']);
            fprintf(fileID,['standard_deviation_method_1:',num2str(std_1),'\n']);
            fprintf(fileID,['standard_deviation_method_1:',num2str(std_2)]);
            fclose(fileID); 
        end        

        function finish_experiment(app)
            app.Button2.Enable='off';
            app.Button3.Enable='off';
            if(app.interleaver.trials <= app.interleaver.max_trials) 
                app.NumericEditField8.Value = app.interleaver.calculate_result();
                app.EditField2.Value = 'Converged';
            else
                app.NumericEditField8.Value = 0;
                app.EditField2.Value = 'Exceeded max trials';
            end
            save_results(app)
        end

    end

    methods (Access = public)
    
        function disable_options(app)
            app.NumericEditField.Enable='off';
            app.NumericEditField4.Enable='off';
            app.NumericEditField5.Enable='off';
            app.NumericEditField6.Enable='off';
            app.NumericEditField7.Enable='off';
            app.NumericEditField2.Enable='off';
            app.NumericEditField3.Enable='off';
            app.EditField.Enable = 'off';
            app.DropDown.Enable='off';
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            devID = findMOTU24;
            if ~playrec('isInitialised');
                playrec('init', 44100, devID, -1, 24)
            end
        end

        % Button pushed function: Button
        function start(app, event)
            init_soa_asc = app.NumericEditField.Value;
            init_soa_desc = app.NumericEditField4.Value;
            init_step = app.NumericEditField5.Value;
            step = app.NumericEditField6.Value;
            reversals = app.NumericEditField7.Value;
            max_trials = app.NumericEditField2.Value;
            threshold = app.NumericEditField3.Value;
            modulation = lower(app.DropDown.Value);
            switch modulation
                case 'sine'
                    modulation_type = 1;
                case 'exponential'
                    modulation_type = 2;
                case 'linear'
                    modulation_type = 3;
                case 'gaussian'
                    modulation_type = 4;
                case 'sine_sq'
                    modulation_type = 5';
                otherwise
                    modulation_type = 0;
            end
            id = app.EditField.Value;
           
            try
                intensities = generate_intensities(id, threshold);
                app.SOAs = [init_soa_desc, init_soa_asc];
                app.trials = 0;
                app.disable_options();
                app.interleaver = InterleaveManager(intensities, max_trials, init_soa_desc, init_soa_asc, modulation_type, init_step, step, reversals);
                
                app.Button2.Enable='on';
                app.Button3.Enable='on';
                
            catch  Exception
                msgbox(['Error starting: ', getReport(Exception,'basic')]);
            end
            %disp(['Participant ID: ',id,' Modulation: ',modulation,' SOA: ', num2str(init_soa), ' Duration: ', num2str(duration), ' Threshold: ', num2str(threshold)]);
        end

        % Button pushed function: Button2
        function Yes(app, event)
            app.Button2.Enable='off';
            app.Button3.Enable='off';
            performed = app.interleaver.perform_trial(-1);
            if(performed) 
                new_SOAs = app.interleaver.get_methods_progress();
                app.SOAs = [app.SOAs; new_SOAs];
                app.trials = app.trials+1;
                
                app.Button2.Enable='on';
                app.Button3.Enable='on';
            else
                finish_experiment(app);
            end
            
        end

        % Button pushed function: Button3
        function No(app, event)
            app.Button2.Enable='off';
            app.Button3.Enable='off';
            performed = app.interleaver.perform_trial(1);
            if(performed) 
                new_SOAs = app.interleaver.get_methods_progress();
                app.SOAs = [app.SOAs; new_SOAs];
                app.trials = app.trials+1;
                 
                app.Button2.Enable='on';
                app.Button3.Enable='on';
            else
                finish_experiment(app);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if playrec('isInitialised')
                playrec('reset')
            end
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Parameters';
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'Maximum trials';
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField2.Value = 100;
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Ascending Initial SOA (ms)';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.NumericEditField.Value = 50;
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.Button.Text = 'START';
            app.LabelNumericEditField3 = uilabel(app.Panel);
            app.LabelNumericEditField3.Text = 'Threshold at 300 Hz (dB)';
            app.NumericEditField3 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField3.Value = -48;
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Modulation Type';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Gaussian', 'Linear', 'Sine', 'SIne_Sq'};
            app.DropDown.Value = 'Gaussian';
            app.LabelEditField = uilabel(app.Panel);
            app.LabelEditField.Text = 'Participant ID';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Value = 'JUAN';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Method Parameters:';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Descending Initial SOA (ms)';
            app.NumericEditField4 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField4.Value = 350;
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Initial Step Size (ms)';
            app.NumericEditField5 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField5.Value = 55;
            app.Label4 = uilabel(app.Panel);
            app.Label4.Text = 'Step Size (ms)';
            app.NumericEditField6 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField6.Value = 30;
            app.Label5 = uilabel(app.Panel);
            app.Label5.Text = 'Reversals Per Method';
            app.NumericEditField7 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField7.Value = 6;
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Yes, true);
            app.Button2.Enable = 'off';
            app.Button2.Text = 'Yes';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @No, true);
            app.Button3.Enable = 'off';
            app.Button3.Text = 'No';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Is the signal "wavy"?';
            app.LabelNumericEditField8 = uilabel(app.UIFigure);
            app.LabelNumericEditField8.Text = 'SOA Result (ms):';
            app.NumericEditField8 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField8.Editable = 'off';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.Icon = 'IMG_0013.jpg';
            app.Button4.Text = '';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.Icon = 'IMG_0012.jpg';
            app.Button5.Text = '';
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'Status';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.Editable = 'off';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Wavy';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'Not wavy';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LowerSOA_Participant()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end